classdef APP_PRUEBA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        IniciarButton            matlab.ui.control.Button
        DNIEditFieldLabel        matlab.ui.control.Label
        DNIEditField             matlab.ui.control.EditField
        EnviarButton             matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        OperadoraTelefnicaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        estado ='no ready'
        dni=''
        Fs=24000
        Bienvenida=audioread('Audios/Bienvenida.mp3')
        Pedido_DNI=audioread('Audios/PEDIDO_DNI.mp3')
        Dni_valido=audioread('Audios/DNI_VALIDO.mp3')
        Ayuda=audioread('Audios/en_que_puedo_ayudarte.wav')
        Conexion_wifi=audioread('Audios/Conexionwifi.mp3')
        verificacion_conexion=audioread('Audios/Verificar_conexion_nuevo.mp3')
        solucion_problema=audioread('Audios/Solucion_problema.mp3' )
        color_luces=audioread('Audios/Color_luces.mp3' )
        desconectar=audioread('Audios/Desconectar.mp3' )
        proximas_horas=audioread('Audios/proximas_horas.mp3' )
        gracias=audioread('Audios/Gracias.mp3' )
        Pregunta_velocidad=audioread("Audios/Pregunta_velocidad.mp3")
        desconectar_conectar=audioread('Audios/Desconectar_conectar_cable.mp3' )
        cerca_modem=audioread('Audios/cerca_modem.mp3' )
        acceso_instalaciones=audioread('Audios/Accediendo_instalaciones.mp3' )
        fallas_hardware=audioread('Audios/Fallas_hardware.mp3' )
        mala_config=audioread('Audios/Mala_configuracion.mp3' )
        ubicar_facil=audioread('Audios/Ubicar_faciAcceso.mp3' )
        problema_persiste=audioread('Audios/Problema_persiste.mp3' )
        gusto_ayudar=audioread('Audios/Mensaje_finalAyuda.mp3' )
        GraciasClaro=audioread("Audios/Gracias_llamar_claro.mp3")
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Label.Text=app.Button_2.Text
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Label.Text=app.Button.Text
            
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            app.Label.Text='Bienvenido a Claro.Por favor ingrese su numero de DNI'
            soundsc(app.Bienvenida,app.Fs)
            pause(2)
            soundsc(app.Pedido_DNI,app.Fs)
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            
            if app.DNIEditField.Value=='12345678'
                app.Label.Text='Muchas gracias, ¿En qué puedo ayudarte?';
                app.estado ='ready';
                soundsc(app.Ayuda,app.Fs)
                pause(5)
                Reconocimiento_voz_frase
                if predicted =='internet'
                    app.Label.Text='Verifique la conexión del cable ethernet. Desconecte  y vuelve a conectar el cable ethernet. '
                    soundsc(app.verificacion_conexion,app.Fs)
                    pause(12)
                    soundsc(app.solucion_problema,app.Fs)
                    pause(6)
                    Reconocimiento_voz_binario
                    if(predicted=="no")
                        app.Label.Text='¿Que color observa en su modem?'
                        soundsc(app.color_luces,app.Fs)
                        pause(5)
                        Reconocimiento_voz_color
                        %si es rojo y no es amarillo
                        if(predicted=="rojo")
                            app.Label.Text='Desconecte y vuelva a encender el módem. ¿Se solucionó el problema? Responda Si o No'
                            soundsc(app.desconectar,app.Fs)
                            pause(9)
                            Reconocimiento_voz_binario
                            if(predicted=="si")
                                app.Label.Text='Gracias por su atención'
                                soundsc(app.gracias,app.Fs)
                                pause(6)
                            else
                                app.Label.Text='En las próximas horas, un técnico se acercará a su domicilio'
                                soundsc(app.proximas_horas,app.Fs)
                                pause(10)
                            end
                        else
                            app.Label.Text='En las próximas horas, un técnico se acercará a su domicilio'
                            soundsc(app.proximas_horas,app.Fs)
                            pause(10)
                        end
                    else
                        app.Label.Text='Gracias por su atencion'
                        soundsc(app.gracias,app.Fs)
                        pause(6)
                    end
                    app.Label.Text='Gracias por llamar a Claro'
                    soundsc(app.GraciasClaro,app.Fs)
                    pause(5)
                else
                    app.Label.Text='¿Me podria indicar la velocidad de su plan? Indique si es de 50,100 , 200 Mbps'
                    soundsc(app.Pregunta_velocidad,app.Fs)
                    pause(10)
                    Reconocimiento_voz_numeros
                    app.Label.Text=strcat('Tu velocidad es de: ', predicted, ' Mbps')
                    pause(5)
                    soundsc(app.Conexion_wifi,app.Fs)
                    pause(8)
                    Reconocimiento_voz_binario
                    if(predicted=="si")
                        app.Label.Text='¿Se encuentra cerca a la ubicacion de su modem?'
                        soundsc(app.cerca_modem,app.Fs)
                        pause(8)
                        Reconocimiento_voz_binario
                        if(predicted=="si")
                            app.Label.Text='Se esta accediendo a su modem desde nuestras instalaciones. Me podria indicar de que color se observa el led del modem?'
                            soundsc(app.acceso_instalaciones,app.Fs)
                            pause(10)
                            Reconocimiento_voz_color
                            if(predicted=="rojo")
                                app.Label.Text='Se trata de una mala configuracion del equipo. Se le asignara un tecnico para solucionar ese inconveniente'
                                soundsc(app.mala_config,app.Fs)
                                pause(10)
                            else
                                app.Label.Text='El modem presenta fallas de hardware. En los proximos dias se le asginara un nuevo equipo.'
                                soundsc(app.fallas_hardware,app.Fs)
                                pause(10)
                            end
                        else
                            app.Label.Text='Intente ubicar su modem en un lugar de facil acceso. Eso solucionara el problema'
                            soundsc(app.ubicar_facil,app.Fs)
                            pause(10)
                        end
                    else
                        app.Label.Text='Por favor, intente desconectar y conectar el cable ethernet.'
                        soundsc(app.desconectar_conectar,app.Fs)
                        pause(8)
                        app.Label.Text='¿El problema persiste?'
                        soundsc(app.problema_persiste,app.Fs)
                        pause(6)
                        Reconocimiento_voz_binario
                        if(predicted=="si")
                            app.Label.Text='En las proximas horas se le asignara un tecnico a su domicilio'
                            soundsc(app.proximas_horas,app.Fs)
                            pause(6)
                        else
                            app.Label.Text='Un gusto haberlo podido ayudar'
                            soundsc(app.gusto_ayudar,app.Fs)
                            pause(6)
                        end
                    end
                    app.Label.Text='Gracias por llamar a claro'
                    soundsc(app.GraciasClaro,app.Fs)
                    pause(5)
                end
                    
            else
                app.Label.Text='Ingrese un DNI valido'
                soundsc(app.Dni_valido,app.Fs)
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.DNIEditFieldLabel = uilabel(app.UIFigure);
            app.DNIEditFieldLabel.Text = 'DNI:';
            app.DNIEditField = uieditfield(app.UIFigure, 'text');
            app.EnviarButton = uibutton(app.UIFigure, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Text = 'Enviar';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '9';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Claro.png';
            app.OperadoraTelefnicaLabel = uilabel(app.UIFigure);
            app.OperadoraTelefnicaLabel.Text = 'Operadora Telefónica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_PRUEBA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end